classdef SimpleQRcode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleQRcodeUIFigure  matlab.ui.Figure
        Label                 matlab.ui.control.Label
        txtContent            matlab.ui.control.EditField
        btnGene               matlab.ui.control.Button
        btnReco               matlab.ui.control.Button
        btnSave               matlab.ui.control.Button
        axesImg               matlab.ui.control.UIAxes
    end

    % 二维码宽高
    properties (Access = private)
        width  = 256      % 二维码宽度
        heigth = 256      % 二维码高度
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGene, btnReco, btnSave
        function ButtonPushed(app, event)
%             import com.google.zxing.*
            btn = event.Source.Text;
            if all(btn == '生成二维码')
                content = app.txtContent.Value;
                app.axesImg.PickableParts = 'none';   % 坐标轴不可点击，不会显示坐标轴工具栏
                if isempty(content)
                    app.txtContent.Value = 'Here is the content.';
                    content = 'Here is the content.';
                end
                % 完整路径
                zxingPath = 'D:\MyPrograms\Matlab2016B\MatlabSamples\6. AppDesigner案例\SimpleQRcode\zxing.jar';
                if ~exist(zxingPath, 'file')
                    return;
                end
                cleanUp = onCleanup(@()javarmpath(zxingPath));
                javaaddpath(zxingPath);
                writer = com.google.zxing.MultiFormatWriter();
                bitmtx = writer.encode(content, com.google.zxing.BarcodeFormat.QR_CODE, app.width, app.heigth);
                mtx = char(bitmtx);
                clear bitmtx writer
                mtx(mtx == 10) = [];  % remove \n
                mtx = reshape(mtx(1:2:end), app.width, app.heigth);  % remove extra space and transpose
                mtx(mtx~='X') = 1;
                mtx(mtx=='X') = 0;
                mtx = double(mtx);
                imshow(mtx, 'Border', 'tight', 'Parent', app.axesImg);
            elseif all(btn == '识别二维码')
                [fileName, pathName] = uigetfile({'*.png', 'PNG File(*.png)'; '*.bmp', 'BMP File(*.bmp)'; '*.jpg', 'JPG File(*.jpg)';}, 'Choose File');
                imgPath = [pathName, fileName];
                if isequal(fileName, 0) || isequal(pathName, 0)
                	return;
                end
                imshow(imgPath, 'Border', 'tight', 'Parent', app.axesImg);
                img = imread(imgPath); 
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                zxingPath = fullfile(fileparts(mfilename('fullpath')), 'zxing.jar');  % full path
                if ~exist(zxingPath, 'file')
                    return;
                end
                javaaddpath(zxingPath);                
                img = im2java(img);
                img.getWidth();   % if getWidth is not called, getBufferedImage will fail.
                img.getHeight(); 
                source = client.j2se.BufferedImageLuminanceSource(img.getBufferedImage());
                binarizer = common.HybridBinarizer(source);
                bitmap = BinaryBitmap(binarizer);
                reader = MultiFormatReader();
                ret = char(reader.decode(bitmap));
                app.txtContent.Value = ret;
            elseif all(btn == '保存二维码')
                [fileName, pathName] = uiputfile({'*.png', 'PNG File(*.png)'; '*.bmp', 'BMP File(*.bmp)'; '*.jpg', 'JPG File(*.jpg)';}, 'Choose File');
                imgPath = [pathName, fileName];
                if isequal(pathName, 0) || isequal(fileName, 0)
                    return
                end
                % 直接 get 数据保存成图像
                if ~isempty(app.axesImg.Children)
                    imwrite(app.axesImg.Children.CData, imgPath);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleQRcodeUIFigure = uifigure('Visible', 'off');
            app.SimpleQRcodeUIFigure.Name = 'SimpleQRcode';
            app.Label = uilabel(app.SimpleQRcodeUIFigure);
            app.Label.Text = '英文内容';
            app.txtContent = uieditfield(app.SimpleQRcodeUIFigure, 'text');
            app.txtContent.Value = 'Here is the content.';
            app.btnGene = uibutton(app.SimpleQRcodeUIFigure, 'push');
            app.btnGene.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnGene.Text = '生成二维码';
            app.btnReco = uibutton(app.SimpleQRcodeUIFigure, 'push');
            app.btnReco.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnReco.Text = '识别二维码';
            app.btnSave = uibutton(app.SimpleQRcodeUIFigure, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnSave.Text = '保存二维码';
            app.axesImg = uiaxes(app.SimpleQRcodeUIFigure);
            app.axesImg.XTick = [];
            app.axesImg.YTick = [];
            % Show the figure after all components are created
            app.SimpleQRcodeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleQRcode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleQRcodeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleQRcodeUIFigure)
        end
    end
end