classdef SaveAxesAsImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DrawsomethingButton  matlab.ui.control.Button
        SaveasimageButton    matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawsomethingButton, 
        % SaveasimageButton
        function ButtonPushed(app, event)
            btn = event.Source.Text;
            if strcmp(btn, 'Draw something')
                x = 0:0.01:12;
                y1 = sin(x);
                y2 = cos(x);
                plot(app.UIAxes, x, y1, x, y2);
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'y');
                title(app.UIAxes, 'sin(x) vs cos(x)');
                legend(app.UIAxes, 'sin(x)', 'cos(x)');
                app.UIAxes.Box = 'on';
            else
                % creat new figure
                f = figure('visible', 'off'); 
                % copy axes
                copyobj(app.UIAxes, f);
                % set size
                set(gca,'Units', 'normalized', 'Position', [0.05 0.05 0.85 0.85]);
                % show legend
                legend
                % save as image
                saveas(f, 'output.png');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DrawsomethingButton = uibutton(app.UIFigure, 'push');
            app.DrawsomethingButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DrawsomethingButton.Text = 'Draw something';
            app.SaveasimageButton = uibutton(app.UIFigure, 'push');
            app.SaveasimageButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SaveasimageButton.Text = 'Save as image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveAxesAsImage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end