classdef SimpleCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleCalUIFigure  matlab.ui.Figure
        Label              matlab.ui.control.Label
        num0               matlab.ui.control.Button
        num1               matlab.ui.control.Button
        num2               matlab.ui.control.Button
        num3               matlab.ui.control.Button
        num4               matlab.ui.control.Button
        num5               matlab.ui.control.Button
        num6               matlab.ui.control.Button
        num7               matlab.ui.control.Button
        num8               matlab.ui.control.Button
        num9               matlab.ui.control.Button
        add                matlab.ui.control.Button
        sub                matlab.ui.control.Button
        mul                matlab.ui.control.Button
        div                matlab.ui.control.Button
        equ                matlab.ui.control.Button
        clc                matlab.ui.control.Button
        dot                matlab.ui.control.Button
        back               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: add, back, clc, div, dot, equ, 
        % mul, num0, num1, num2, num3, num4, num5, num6, num7, 
        % num8, num9, sub
        function ButtonPushed(app, event)
            % 获取按钮信息
            btn = event.Source.Text;
            % 获取显示信息
            str = get(app.Label, 'Text');
            % 如果包含了等号直接退出
            if ~(btn == 'C' || btn == '←')
                if any(str == '=')
                    return;
                end
            end
            % 清空
            if btn == 'C'
                app.Label.Text = '0';
            % 后退
            elseif btn == '←'
                if length(str) == 1
                    app.Label.Text = '0'; 
                else
                    str(end) = [];
                    app.Label.Text = str;
                end
            % 加减乘除
            elseif btn == '+' || btn == '-' || btn == '×' || btn == '÷'                
                ch = str(end);
                if ch == '+' || ch == '-' || ch == '×' || ch == '÷'
                    str(end) = btn;
                elseif ch == '.'
                    str(end) = [];
                    str = [str, btn];
                else
                    str = [str, btn];
                end 
                app.Label.Text = str;
            % 等于
            elseif btn == '='
                ch = str(end);
                if ch == '+' || ch == '-' || ch == '×' || ch == '÷' || ch == '='
                    str(end) = [];
                end
                str0 = str;
                str = strrep(str, '×', '*');
                str = strrep(str, '÷', '/');
                result = eval(str);
                app.Label.Text = [str0, '=', num2str(result)];
            % 小数点
            elseif btn == '.'
                ch = str(end);
                if ch == '+' || ch == '-' || ch == '×' || ch == '÷'
                    str = [str, '0.'];    
                elseif ch == '=' || ch == '.'
                else
                    % 找最后一个点的位置
                    pos = find(str=='.', 1, 'last');
                    if ~isempty(pos) && ~isnan(str2double(str(pos+1:end)))
                        
                    else
                      str = [str, '.'];  
                    end
                end
                app.Label.Text = str;
            % 数字
            else
                % 判断是否为初始状态
                if str == '0'
                    str = [];
                end
                str = [str, btn];
                app.Label.Text = str;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleCalUIFigure = uifigure('Visible', 'off');
            app.SimpleCalUIFigure.Name = 'SimpleCal';
            app.Label = uilabel(app.SimpleCalUIFigure);
            app.Label.Text = '0';
            app.num0 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num0.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num0.Text = '0';
            app.num1 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num1.Text = '1';
            app.num2 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num2.Text = '2';
            app.num3 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num3.Text = '3';
            app.num4 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num4.Text = '4';
            app.num5 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num5.Text = '5';
            app.num6 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num6.Text = '6';
            app.num7 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num7.Text = '7';
            app.num8 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num8.Text = '8';
            app.num9 = uibutton(app.SimpleCalUIFigure, 'push');
            app.num9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.num9.Text = '9';
            app.add = uibutton(app.SimpleCalUIFigure, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.add.Text = '+';
            app.sub = uibutton(app.SimpleCalUIFigure, 'push');
            app.sub.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.sub.Text = '-';
            app.mul = uibutton(app.SimpleCalUIFigure, 'push');
            app.mul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.mul.Text = '×';
            app.div = uibutton(app.SimpleCalUIFigure, 'push');
            app.div.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.div.Text = '÷';
            app.equ = uibutton(app.SimpleCalUIFigure, 'push');
            app.equ.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.equ.Text = '=';
            app.clc = uibutton(app.SimpleCalUIFigure, 'push');
            app.clc.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.clc.Tooltip = {'clear'};
            app.clc.Text = 'C';
            app.dot = uibutton(app.SimpleCalUIFigure, 'push');
            app.dot.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.dot.Text = '.';
            app.back = uibutton(app.SimpleCalUIFigure, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.back.Tooltip = {'back'};
            app.back.Text = '←';
            % Show the figure after all components are created
            app.SimpleCalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleCal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleCalUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleCalUIFigure)
        end
    end
end