classdef SimpleImageToData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleImageToDataUIFigure  matlab.ui.Figure
        tableData                  matlab.ui.control.Table
        btnLoad                    matlab.ui.control.Button
        xScale                     matlab.ui.container.ButtonGroup
        xLin                       matlab.ui.control.RadioButton
        xLog                       matlab.ui.control.RadioButton
        yScale                     matlab.ui.container.ButtonGroup
        yLin                       matlab.ui.control.RadioButton
        yLog                       matlab.ui.control.RadioButton
        XEditFieldLabel            matlab.ui.control.Label
        editX1                     matlab.ui.control.NumericEditField
        XLabel                     matlab.ui.control.Label
        editX2                     matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        editY1                     matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        editY2                     matlab.ui.control.NumericEditField
        btnAuto                    matlab.ui.control.Button
        btnSave                    matlab.ui.control.Button
        selMode                    matlab.ui.container.ButtonGroup
        selManu                    matlab.ui.control.RadioButton
        selAuto                    matlab.ui.control.RadioButton
        axesImg                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X1 = 0         % 坐标轴x起点位置
        X2 = 1         % 坐标轴x终点位置
        Y1 = 0         % 坐标轴y起点位置
        Y2 = 1         % 坐标轴y终点位置
        XPos1          % 图像x起点位置
        XPos2          % 图像x终点位置
        YPos1          % 图像y起点位置
        YPos2          % 图像y终点位置
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnAuto, btnLoad, btnSave
        function ButtonPushed(app, event)
            btn = event.Source.Text;
            if strcmp(btn, '加载图像')
                [fileName, pathName] = uigetfile({'*.png', 'PNG File(*.png)'; '*.bmp', 'BMP File(*.bmp)'; '*.jpg', 'JPG File(*.jpg)';}, 'Choose File');
                imgPath = [pathName, fileName];
                if isequal(fileName, 0) || isequal(pathName, 0)
                	return;
                end
                imshow(imgPath, 'Border', 'tight', 'Parent', app.axesImg); 
                % 数据清空
                app.tableData.Data = [];   
                app.XPos1 = [];
                app.XPos2 = [];
                app.YPos1 = [];
                app.YPos2 = [];
                % 坐标轴范围
                xRange = app.axesImg.XLim;
                yRange = app.axesImg.YLim;
                app.X1 = xRange(1);
                app.X2 = xRange(2);
                app.Y1 = yRange(1);
                app.Y2 = yRange(2);
                x = [app.X1, app.X2, app.X2, app.X1, app.X1];
                y = [app.Y1, app.Y1, app.Y2, app.Y2, app.Y1];
                % 圈定范围
                line(app.axesImg, x, y); 
            elseif strcmp(btn, '重新定位')
                app.XPos1 = [];
                app.XPos2 = [];
                app.YPos1 = [];
                app.YPos2 = [];
                app.tableData.Data = []; 
                % 删除绘制的线
                lines = app.axesImg.findobj('type', 'line');
                for i = 1:length(lines)
                    delete(lines(i));
                end
            else
                [fileName, pathName] = uiputfile('*.txt', 'Save As Text');
                data = app.tableData.Data;
                if ~isempty(data)
                    m = size(data, 1);
                    output = zeros(m, 2);
                    for i = 1:m
                        output(i, 1) = str2double(data(i, 2));
                        output(i, 2) = str2double(data(i, 3));
                    end
                    dlmwrite([pathName, fileName], output, 'delimiter', '\t', 'precision', '%8.4f');
                end
            end
        end

        % Window button down function: SimpleImageToDataUIFigure
        function SimpleImageToDataUIFigureWindowButtonDown(app, event)
            % 没有加载图像
            if isempty(app.axesImg.Children)
                return;
            end
            % 没有设置图像范围
            % x起点坐标
            if isempty(app.XPos1) || isempty(app.YPos1)
                curPt = app.axesImg.CurrentPoint;
                x0 = curPt(1, 1);
                y0 = curPt(1, 2);
                line(app.axesImg, x0, y0, 'marker', '.', 'color', 'r', 'markersize', 20);
                app.XPos1 = x0;
                app.YPos1 = y0;
                return;
            end
            % x终点坐标
            if isempty(app.XPos2)
                curPt = app.axesImg.CurrentPoint;
                x1 = curPt(1, 1);
                line(app.axesImg, [app.XPos1, x1], [app.YPos1, app.YPos1], 'LineWidth', 1.5, 'marker', '.', 'color', 'r', 'markersize', 20);
                app.XPos2 = x1;
                return;
            end
            % y终点坐标
            if isempty(app.YPos2)
                curPt = app.axesImg.CurrentPoint;
                y1 = curPt(1, 2);
                line(app.axesImg, [app.XPos1, app.XPos1], [app.YPos1, y1], 'LineWidth', 1.5, 'marker', '.', 'color', 'r', 'markersize', 20);  
                app.YPos2 = y1;
                return;
            end
            % x轴类型
            isXLinear = true;
            if app.xScale.Buttons(1, 1).Value == 0
                isXLinear = false;
            end
            % y轴类型
            isYLinear = true;
            if app.yScale.Buttons(1, 1).Value == 0
                isYLinear = false;
            end
            % 取点方式
            isSelModeManu = true;
            if app.selMode.Buttons(1, 1).Value == 0
                isSelModeManu = false;
            end
            % 数据范围
            xmin = app.editX1.Value;
            xmax = app.editX2.Value;
            ymin = app.editY1.Value;
            ymax = app.editY2.Value;
            % 手动取点
            if isSelModeManu
                % 获取鼠标点坐标
                curPt = app.axesImg.CurrentPoint;
                x = curPt(1, 1);
                y = curPt(1, 2);
                % 限定范围
                if x < app.X1 || x > app.X2 || y < app.Y1 || y > app.Y2
                    return;
                end
                % 绘制点
                line(app.axesImg, x, y, 'marker', '.', 'color', 'b', 'markersize', 20);
                % 转换坐标
                if isXLinear
                    x = (xmax - xmin) * (x - app.XPos1) / (app.XPos2 - app.XPos1) + xmin;
                else
                    x = 10^((log10(xmax) - log10(xmin)) * (x - app.XPos1) / (app.XPos2 - app.XPos1) + log10(xmin));
                end
                if isYLinear
                    y = (ymax - ymin) * (y - app.YPos1) / (app.YPos2 - app.YPos1) + ymin;
                else
                    y = 10^((log10(ymax) - log10(ymin)) * (y - app.YPos1) / (app.YPos2 - app.YPos1) + log10(ymin));
                end
                % 更新表格数据
                data = app.tableData.Data;
                data = [data; {num2str(size(data, 1) + 1), num2str(x), num2str(y)}];
                app.tableData.Data = data;
            else
                % 获取图像
                img = app.axesImg.findobj('type', 'image');
                % 获取图像数据
                data = img.CData;
                if size(data, 3) == 3
                    data = rgb2gray(data);
                end
                % 裁剪数据
                x1 = round(app.XPos1);
                x2 = round(app.XPos2);
                y1 = round(app.YPos1);
                y2 = round(app.YPos2);
                % 图像和数据上下颠倒
                data = data(y2:y1, x1:x2);
                data = imbinarize(data);
                % 边框、刻度线简单处理
                % h个像素长度
                h = 10;  
                data(1:h, :) = 1;
                data(end - h + 1:end, :) = 1;
                data(:, 1:h) = 1;
                data(:, end - h + 1:end) = 1;
                data = 1 - data;
                [m, n] = size(data);
                xs = nan(n, 1);
                ys = nan(n, 1);
                xs1 = xs;
                ys1 = ys;
                for i = 1:n
                    col = data(:, i);
                    % 图像和数据上下颠倒
                    if any(col)
                        ind = m - find(col == 1);
                        ys(i) = mean(ind) * (ymax - ymin) / m + ymin;
                        xs(i) = i * (xmax - xmin) / n + xmin;
                        xs1(i) = i + x1;
                        ys1(i) = m - mean(ind) + y2;
                    end
                end
                xs(isnan(xs)) = [];
                ys(isnan(ys)) = [];
                xs1(isnan(xs1)) = [];
                ys1(isnan(ys1)) = [];
                % 绘制
                line(app.axesImg, xs1, ys1, 'LineWidth', 1.5, 'color', 'b');
                % 更新表格数据
                data = app.tableData.Data;
                len = length(xs);
                data1 = cell(len, 3);
                for i = 1:len
                   data1(i, :) = {num2str(size(data, 1) + i), num2str(xs(i)), num2str(ys(i))};
                end
                app.tableData.Data = [data; data1];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleImageToDataUIFigure = uifigure('Visible', 'off');
            app.SimpleImageToDataUIFigure.Name = 'SimpleImageToData';
            app.SimpleImageToDataUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @SimpleImageToDataUIFigureWindowButtonDown, true);
            app.tableData = uitable(app.SimpleImageToDataUIFigure);
            app.tableData.ColumnName = {'序号'; 'X'; 'Y'};
            app.tableData.RowName = {};
            app.tableData.ColumnSortable = true;
            app.btnLoad = uibutton(app.SimpleImageToDataUIFigure, 'push');
            app.btnLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnLoad.Text = '加载图像';
            app.xScale = uibuttongroup(app.SimpleImageToDataUIFigure);
            app.xScale.Title = 'X轴类型';
            app.xLin = uiradiobutton(app.xScale);
            app.xLin.Text = '线性';
            app.xLin.Value = true;
            app.xLog = uiradiobutton(app.xScale);
            app.xLog.Text = '对数';
            app.yScale = uibuttongroup(app.SimpleImageToDataUIFigure);
            app.yScale.Title = 'Y轴类型';
            app.yLin = uiradiobutton(app.yScale);
            app.yLin.Text = '线性';
            app.yLin.Value = true;
            app.yLog = uiradiobutton(app.yScale);
            app.yLog.Text = '对数';
            app.XEditFieldLabel = uilabel(app.SimpleImageToDataUIFigure);
            app.XEditFieldLabel.Text = 'X起点值';
            app.editX1 = uieditfield(app.SimpleImageToDataUIFigure, 'numeric');
            app.XLabel = uilabel(app.SimpleImageToDataUIFigure);
            app.XLabel.Text = 'X终点值';
            app.editX2 = uieditfield(app.SimpleImageToDataUIFigure, 'numeric');
            app.editX2.Value = 1;
            app.YEditFieldLabel = uilabel(app.SimpleImageToDataUIFigure);
            app.YEditFieldLabel.Text = 'Y起点值';
            app.editY1 = uieditfield(app.SimpleImageToDataUIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.SimpleImageToDataUIFigure);
            app.YEditField_2Label.Text = 'Y终点值';
            app.editY2 = uieditfield(app.SimpleImageToDataUIFigure, 'numeric');
            app.editY2.Value = 1;
            app.btnAuto = uibutton(app.SimpleImageToDataUIFigure, 'push');
            app.btnAuto.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnAuto.Text = '重新定位';
            app.btnSave = uibutton(app.SimpleImageToDataUIFigure, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnSave.Text = '保存数据';
            app.selMode = uibuttongroup(app.SimpleImageToDataUIFigure);
            app.selMode.Title = '取点方式';
            app.selManu = uiradiobutton(app.selMode);
            app.selManu.Text = '手动';
            app.selManu.Value = true;
            app.selAuto = uiradiobutton(app.selMode);
            app.selAuto.Text = '自动';
            app.axesImg = uiaxes(app.SimpleImageToDataUIFigure);
            app.axesImg.XTick = [];
            app.axesImg.YTick = [];
            app.axesImg.Visible = 'off';
            % Show the figure after all components are created
            app.SimpleImageToDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleImageToData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleImageToDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleImageToDataUIFigure)
        end
    end
end