classdef SimpleMemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleMemoUIFigure  matlab.ui.Figure
        Label               matlab.ui.control.Label
        editContent         matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        editTime            matlab.ui.control.EditField
        tableContent        matlab.ui.control.Table
        btnAdd              matlab.ui.control.Button
        btnDel              matlab.ui.control.Button
        btnRun              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Indices = 0  % 表格行号索引
        Timer        % 计时器
    end
    
    methods (Access = private)   
        % 计时器执行函数
        function TimeUpdate(app)
            time = floor(clock);
            hour = time(4);
            minute = time(5);
            shour = num2str(hour);
            sminute = num2str(minute);
            if hour < 10
                shour = ['0' num2str(hour)];
            end
            if minute < 10
                sminute = ['0' num2str(minute)];
            end
            things = app.tableContent.Data;
            for i = 1:size(things, 1)
                if strcmp(things{i, 2}, [shour ':' sminute])
                    % 删除计时器
                    stop(app.Timer);
                    delete(app.Timer);
                    % 通知发声
                    load train.mat y
                    sound(y);
                    % 弹窗
                    msgbox(things{i, 3}, '提醒');                   
                    return;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnAdd, btnDel, btnRun
        function ButtonPushed(app, event)
            btn = event.Source.Text;
            if strcmp(btn, '添加')
                content = app.editContent.Value;
                tt = app.editTime.Value;
                if isempty(content) || isempty(tt)
                    return;
                end
                % 时间格式分割
                try  
                    strs = regexp(tt, ':', 'split');
                    hour = str2double(strs{1,1});
                    minu = str2double(strs{1,2});
                    if hour < 0 || hour >23 || minu < 0 || minu > 59
                        return;
                    end
                    if hour < 10 
                        hour = ['0', num2str(hour)];
                    else
                        hour = num2str(hour);
                    end
                    if  minu < 10
                        minu = ['0', num2str(minu)];
                    else
                        minu = num2str(minu);
                    end
                    
                    things = app.tableContent.Data;
                    things = [things; {num2str(size(things, 1) + 1), [hour, ':', minu], content}];
                    app.tableContent.Data = things;
                catch 
                    return;
                end             
            elseif strcmp(btn, '删除')
                ind = app.Indices;
                things = app.tableContent.Data;
                if ind == 0
                    % 默认删除最后一个
                    things(end, :) = [];
                elseif ind < size(app.tableContent.Data, 1)
                    % 删除选中
                    things(ind, :) = [];
                    app.Indices = 0;
                end
                app.tableContent.Data = things;
            elseif strcmp(btn, '运行')
                things = app.tableContent.Data;
                if isempty(things)
                    return;
                end
                % 初始化计时器
                app.Timer = timer;
                app.Timer.StartDelay = 1;
                app.Timer.Period = 1;
                app.Timer.BusyMode = 'queue';
                app.Timer.ExecutionMode = 'fixedRate';
                app.Timer.TimerFcn = @(~, ~)TimeUpdate(app);
                % 运行计时器
                start(app.Timer);
            end
        end

        % Cell selection callback: tableContent
        function CellSelection(app, event)
            ind = event.Indices;  
            app.Indices = ind(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleMemoUIFigure = uifigure('Visible', 'off');
            app.SimpleMemoUIFigure.Name = 'SimpleMemo';
            app.Label = uilabel(app.SimpleMemoUIFigure);
            app.Label.Text = '事件内容：';
            app.editContent = uieditfield(app.SimpleMemoUIFigure, 'text');
            app.Label_2 = uilabel(app.SimpleMemoUIFigure);
            app.Label_2.Text = '提醒时间：';
            app.editTime = uieditfield(app.SimpleMemoUIFigure, 'text');
            app.editTime.Value = '格式限定（hh:mm）';
            app.tableContent = uitable(app.SimpleMemoUIFigure);
            app.tableContent.ColumnName = {'序号'; '时间'; '内容'};
            app.tableContent.RowName = {};
            app.tableContent.ColumnSortable = [false true false];
            app.tableContent.ColumnEditable = [false true true];
            app.tableContent.CellSelectionCallback = createCallbackFcn(app, @CellSelection, true);
            app.btnAdd = uibutton(app.SimpleMemoUIFigure, 'push');
            app.btnAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnAdd.Text = '添加';
            app.btnDel = uibutton(app.SimpleMemoUIFigure, 'push');
            app.btnDel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnDel.Text = '删除';
            app.btnRun = uibutton(app.SimpleMemoUIFigure, 'push');
            app.btnRun.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnRun.Text = '运行';
            % Show the figure after all components are created
            app.SimpleMemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleMemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleMemoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleMemoUIFigure)
        end
    end
end