classdef Tictactoe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TictactoeUIFigure  matlab.ui.Figure
        menuReset          matlab.ui.container.Menu
        menuHelp           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        has_obj = zeros(3, 3);  % 是否有棋子
        cur_x = 0               % 相对坐标
        cur_y = 0               % 相对坐标
        player = false          % 红方先手
        has_winner = false      % 是否有赢家
        game_count = 0          % 总局数
        win_player_r = 0        % 红方胜局
        win_player_b = 0        % 蓝方胜局
        chesses = []            % 绘制的棋子
        player_name = []        % 玩家名字 
    end
    
    methods (Access = private)
        % 初始化棋盘
        function InitialBoarder(app)
            annotation(app.TictactoeUIFigure, 'line', [0 1], [1/3 1/3], 'LineStyle','--');
            annotation(app.TictactoeUIFigure, 'line', [0 1], [2/3 2/3], 'LineStyle','--');
            annotation(app.TictactoeUIFigure, 'line', [1/3 1/3], [0 1], 'LineStyle','--');
            annotation(app.TictactoeUIFigure, 'line', [2/3 2/3], [0 1], 'LineStyle','--');
        end
        
        % 输入玩家名字
        function GetPlayerNames(app)
            names = inputdlg({'Player_1', 'Player_2'}, 'Input player name', [1 50; 1 50], {'Red', 'Blue'}); 
            app.player_name = names;
        end
        % 画棋子
        function DrawChess(app)
            % 棋子位置
            rect = [app.cur_x/3 - 3/12, app.cur_y/3 - 3/12, 1/6, 1/6];
            % 先手红色，后手蓝色
            color = 'r';
            if app.player
                color = 'b';
            end
            a = annotation(app.TictactoeUIFigure, 'ellipse', rect,'Color', color, 'FaceColor', color);
            app.chesses = [app.chesses, a];
        end
        
        % 重置游戏
        function ResetGame(app)
            app.has_winner = false;
            app.has_obj = zeros(3, 3);
            delete(app.chesses);
            app.chesses = [];
        end
        
        % 结束游戏
        function GameOver(app)
            ResetGame(app);
            app.player = false;
            app.game_count = 0;
            app.win_player_b = 0;
            app.win_player_r = 0;
        end
        
        % 检查是否有赢家
        function CheckWinner(app)
            flag = app.has_obj;
            % 至少有 5 个棋子
            if app.has_winner
                return;
            end
            % 赢棋规则
            rule1 = false;
            rule2 = false;
            for i = 1:3
                if all(flag(:, i) == 1) || all(flag(:, i) == 2)
                    rule1 = true;
                end
                if all(flag(i, :) == 1) || all(flag(i, :) == 2)
                    rule2 = true;
                end
            end
            rule3 = all(diag(flag) == 1) || all(diag(flag) == 2);
            rule4 = all(diag(fliplr(flag)) == 1) || all(diag(fliplr(flag)) == 2);
            if rule1 || rule2 || rule3 || rule4
                app.has_winner = true;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 画棋盘
            InitialBoarder(app);
            % 输入玩家名称
            GetPlayerNames(app);
        end

        % Button down function: TictactoeUIFigure
        function TictactoeUIFigureButtonDown(app, event)
            % 当前鼠标点位置
            current_point = app.TictactoeUIFigure.CurrentPoint;
            % 转换成窗体坐标
            app.cur_x = ceil(current_point(1) / 100);
            app.cur_y = ceil(current_point(2) / 100);
            % 如果当前点有棋子则退出
            if app.has_obj(app.cur_y, app.cur_x) > 0
                return;
            else
                % 先手为 1， 后手为 2
                if app.player
                    app.has_obj(app.cur_y, app.cur_x) = 2;
                else
                    app.has_obj(app.cur_y, app.cur_x) = 1;
                end
            end
            % 画棋子
            DrawChess(app);
            % 判断输赢
            CheckWinner(app);
            % 有赢家弹出提示
            if app.has_winner
                % 游戏计数加 1
                app.game_count = app.game_count + 1;
                title = '游戏结束';
                if app.player
                    % 后手胜局加 1
                    app.win_player_b = app.win_player_b + 1;
                    msg = ['胜负已分，', app.player_name{2},  '赢，是否继续？'];
                else
                    % 先手胜局加 1
                    app.win_player_r = app.win_player_r + 1;
                    msg = ['胜负已分，', app.player_name{1},  '赢，是否继续？'];
                end
                selection = uiconfirm(app.TictactoeUIFigure, msg, title,...
                    'Options', {'继续', '结束'}, 'DefaultOption', 1);
                if strcmp(selection, '结束')
                    % 提示
                    msg = sprintf('共进行游戏%d局，%s胜%d局，%s胜%d局', app.game_count, app.player_name{1}, ...
                                  app.win_player_r, app.player_name{2}, app.win_player_b);
                    uiconfirm(app.TictactoeUIFigure, msg, title, "Options", {'确定'});
                    % 游戏结束
                    GameOver(app);
                    return;
                else
                    % 重置游戏
                    ResetGame(app);
                end
            end
            % 平局判断
            if sum(app.has_obj(:) > 0) == 9
                % 游戏计数加 1
                app.game_count = app.game_count + 1;
                uiconfirm(app.TictactoeUIFigure, '平局，开始新一轮游戏', '平局', "Options", {'确定'});
                ResetGame(app);
            end
            % 切换玩家
            app.player = ~app.player;
        end

        % Menu selected function: menuHelp, menuReset
        function MenuSelected(app, event)
            btn = event.Source.Text;
            if strcmp(btn, '重开')
                GameOver(app);
            elseif strcmp(btn, '帮助')
                msg = ['井字棋游戏，红方先手，以下内容为游戏攻略：先手走角，立于不败之地。有以下可能：'...
                    '后手走角：例如先手走左下角，后手走右上角，先手走任意角，后手堵两个角中间，先手再走另一角，胜。'...
                    '后手走右下角，先手走右上角，后手堵中间，先手走左上角，胜。'...
                    '后手走左上角，先手走右下角，胜。'...
                    '后手走边：例如先手走左下角，后手走下侧边，先手走中间，后手堵右上角，先手走左上角，胜。'...
                    '后手走右侧边，先手仍走中间，后手堵右上角，先手走右下角，胜。'...
                    '后手走上侧边，先手仍走中间，后手堵右上角，先手走左上角，胜。'...
                    '后手走左侧边，先手仍走中间，后手堵右上角，先手走右下角，胜。'...
                    '后手走中间：例如先手走左下角，后手走中间，先手走右上角，后手走任意边，平。'...
                    '后手走中间，先手走右上角，后手走任意角，先手占最后一个角，胜。'...
                    '先手走角，立于不败之地；后手走角，更易平局。'...
                    '先手走中间：正常发挥，平。'...
                    '先手走边：正常发挥，平。'...
                    '但根据上面的先手走角，可以知道如果后手遇到先手走角的情况，走中间还是会平的，如下。'...
                    '先手走角：后手千万走中间，正常发挥，平。'...
                    '后手走其余位置，见上。'];
                uiconfirm(app.TictactoeUIFigure, msg, '游戏帮助', "Options", {'确定'});
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TictactoeUIFigure = uifigure('Visible', 'off');
            app.TictactoeUIFigure.Name = 'Tictactoe';
            app.TictactoeUIFigure.ButtonDownFcn = createCallbackFcn(app, @TictactoeUIFigureButtonDown, true);
            app.TictactoeUIFigure.Tag = 'Tictactoe';
            app.menuReset = uimenu(app.TictactoeUIFigure);
            app.menuReset.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.menuReset.Text = '重开';
            app.menuReset.Tag = 'Reset';
            app.menuHelp = uimenu(app.TictactoeUIFigure);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.menuHelp.Text = '帮助';
            app.menuHelp.Tag = 'Help';
            % Show the figure after all components are created
            app.TictactoeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tictactoe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TictactoeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TictactoeUIFigure)
        end
    end
end